classdef Audio < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AudioMainUIFigure         matlab.ui.Figure
        LOADButton                matlab.ui.control.Button
        MERGEButton               matlab.ui.control.Button
        GENERATEButton            matlab.ui.control.Button
        STOPButton                matlab.ui.control.Button
        PAUSEButton               matlab.ui.control.StateButton
        PLAYButton                matlab.ui.control.Button
        AmplitudeALabel           matlab.ui.control.Label
        AmplitudeAEditField       matlab.ui.control.NumericEditField
        FrequencyAEditFieldLabel  matlab.ui.control.Label
        FrequencyAEditField       matlab.ui.control.NumericEditField
        VolumeControlButtonGroup  matlab.ui.container.ButtonGroup
        neg50                     matlab.ui.control.ToggleButton
        Normal                    matlab.ui.control.ToggleButton
        plus50                    matlab.ui.control.ToggleButton
    end


    properties (Access = public)
     aPlayer;
     audio_file;
     audio_file2;
     Fs;                   % samples per second
     StopTime;            % seconds
     Fc = 0;
     path;
     path2;
     x;
     x2;
     Fs2;
     old_x;
     new_x;
     ipaused = 0;
     iplaying = 0; 
     x50;
     x_neg50;
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            [app.audio_file,app.path] = uigetfile({'*.wav'},'Select an audio file');
           
            if (app.audio_file == 0)
                
                 uialert(app.AudioMainUIFigure,'No audio file loaded!','Alert!','Icon','warning');         
                  return;
            else
            [app.x, app.Fs] = audioread([app.path,app.audio_file]);
            app.aPlayer = audioplayer(app.x,app.Fs);
            app.PLAYButton.Enable = 'On';
            app.neg50.Enable= 'On'; 
            app.plus50.Enable= 'On'; 
            app.Normal.Enable = 'On';
            end
        end

        % Close request function: AudioMainUIFigure
        function AudioMainUIFigureCloseRequest(app, event)
            close all
            delete(app)
            Main
        end

        % Button pushed function: LOADButton
        function LOADButtonPushed(app, event)
                 startupFcn(app)
                 app.PAUSEButton.Text = 'PAUSE';
            
        end

        % Callback function
        function PLAYButtonValueChanged(app, event)

        end

        % Callback function
        function VolumeKnobValueChanged(app, event)

        end

        % Callback function
        function PLAYButtonPushed(app, event)
        
        end

        % Value changed function: PAUSEButton
        function PAUSEButtonValueChanged(app, event)
            value = app.PAUSEButton.Value;
                       
            if (isplaying(app.aPlayer) == 1 && strcmp(app.PAUSEButton.Text ,'PAUSE')) 
               app.PAUSEButton.Text =  'RESUME' ;    
               pause(app.aPlayer);
               app.ipaused = 1;
               return;
            end
            if (strcmp(app.PAUSEButton.Text , 'RESUME'))
                
               app.PAUSEButton.Text = 'PAUSE';
               resume(app.aPlayer);
               app.ipaused = 0 ;         
               return;
            end
            if (app.ipaused == 0 || app.iplaying == 0)
                   uialert(app.AudioMainUIFigure,'Audio playback has elapsed','Alert!','Icon','warning'); 
                   app.PAUSEButton.Enable = 'Off';
                   app.STOPButton.Enable = 'Off';
                   app.PAUSEButton.Value = false;
            end    


        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            
            if ((app.iplaying == 0))
                   uialert(app.AudioMainUIFigure,'No audio currently playing','Alert!','Icon','warning'); 
                   app.STOPButton.Enable = 'Off';
                   
            else 
                if(app.iplaying == 1)
                app.STOPButton.Enable = 'Off';
                app.PAUSEButton.Enable = 'Off';
                app.PAUSEButton.Value = false;
                app.PAUSEButton.Text = 'PAUSE';
                app.iplaying = 0; 
                stop(app.aPlayer);
                end
            end
      
        end

        % Button pushed function: PLAYButton
        function PLAYButtonPushed2(app, event)
           
            if (strcmp(app.PAUSEButton.Text,'RESUME') && app.ipaused == 1) 
               app.PAUSEButton.Value = false; 
               app.PAUSEButton.Text = 'PAUSE';
               app.ipaused = 0;
               app.iplaying = 1;
            end
            if (app.audio_file == 0)
               uialert(app.AudioMainUIFigure,'No audio file loaded!','Alert!','Icon','warning'); 
               return                    
            else 
               app.PAUSEButton.Enable = 'On'; 
               app.STOPButton.Enable = 'On'; 
               app.iplaying = 1;
               playblocking(app.aPlayer);                 
         
            end
        end

        % Callback function
        function VolumeKnobValueChanged2(app, event)
               
        end

        % Callback function
        function VolumeKnobValueChanging(app, event)
                          
        end

        % Callback function
        function VolumeSliderValueChanged(app, event)
          
        end

        % Callback function
        function VolumeSliderValueChanging(app, event)

        end

        % Selection changed function: VolumeControlButtonGroup
        function VolumeControlButtonGroupSelectionChanged(app, event)
            selectedButton = app.VolumeControlButtonGroup.SelectedObject;
            app.iplaying = 1;
            STOPButtonPushed(app, event);
            app.x50 = app.x * 1.5;
            app.x_neg50 = app.x * .5;
            if (selectedButton == app.neg50)
              app.aPlayer = audioplayer(app.x_neg50,app.Fs);
            end 
            if (selectedButton == app.plus50)
              app.aPlayer = audioplayer(app.x50,app.Fs);
            end
            if (selectedButton == app.Normal)
              app.aPlayer = audioplayer(app.x,app.Fs);
            end
           
           
        end

        % Button pushed function: MERGEButton
        function MERGEButtonPushed(app, event)
            
            LOADButtonPushed(app,event);
           
                     
            [app.audio_file2,app.path2] = uigetfile({'*.wav'},'Select a second audio file');
            if (app.audio_file2 == 0)                
                 uialert(app.AudioMainUIFigure,'Merge operation canceled!','Alert!','Icon','warning');         
                 return;
            else
                 [app.x2, app.Fs2] = audioread([app.path2,app.audio_file2]); 
                 newFs= max(app.Fs,app.Fs2);      %new frequency  
                 [m,n] = size(app.x);
                 [m2,n2] = size(app.x2);
                 
                 if (n == n2)
                     merged_file = [app.x;app.x2];
                 else  
                   if (n > 1 && n2 == 1)                         %check if any are stereo, in which case convert to mono
                     merged_file = [mean(app.x,2); app.x2];
                   else
                     merged_file = [app.x;mean(app.x2,2)];         %merge files
                   end
                 end 
                 
                 app.x = merged_file;
                 app.aPlayer = audioplayer(merged_file ,newFs);
                 app.iplaying = 1;
                 
            end
            if (app.iplaying ==1)
                   STOPButtonPushed(app, event);
            end
        end

        % Button pushed function: GENERATEButton
        function GENERATEButtonPushed(app, event)
          if  (app.AmplitudeAEditField.Value == 0 || app.FrequencyAEditField.Value == 0)
              uialert(app.AudioMainUIFigure,'Ivalid Frequency or Amplitude. Please input valid values!','Alert!','Icon','warning'); 
          else
            fs = 44100;
            ts = 1/44100;
            T = 3;
            t = 0:ts:T;
            a = app.AmplitudeAEditField.Value*sin(2*pi* app.FrequencyAEditField.Value*t);
            a2 = app.AmplitudeAEditField.Value*sin(2*(pi/2)* (2 *app.FrequencyAEditField.Value)*t);
            a3 = app.AmplitudeAEditField.Value*sin(2*(pi/3)* (3 *app.FrequencyAEditField.Value)*t);
            tone = a + a2 + a3;
            app.aPlayer = audioplayer(tone,fs);
            app.x = tone;
            app.Fs = fs;
            app.PLAYButton.Enable = 'On';
            app.audio_file = 1;
          end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AudioMainUIFigure = uifigure;
            app.AudioMainUIFigure.Name = 'AudioMain';
            app.AudioMainUIFigure.CloseRequestFcn = createCallbackFcn(app, @AudioMainUIFigureCloseRequest, true);
            app.LOADButton = uibutton(app.AudioMainUIFigure, 'push');
            app.LOADButton.ButtonPushedFcn = createCallbackFcn(app, @LOADButtonPushed, true);
            app.LOADButton.Text = 'LOAD';
            app.MERGEButton = uibutton(app.AudioMainUIFigure, 'push');
            app.MERGEButton.ButtonPushedFcn = createCallbackFcn(app, @MERGEButtonPushed, true);
            app.MERGEButton.Text = 'MERGE';
            app.GENERATEButton = uibutton(app.AudioMainUIFigure, 'push');
            app.GENERATEButton.ButtonPushedFcn = createCallbackFcn(app, @GENERATEButtonPushed, true);
            app.GENERATEButton.Text = 'GENERATE';
            app.STOPButton = uibutton(app.AudioMainUIFigure, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Enable = 'off';
            app.STOPButton.Text = 'STOP';
            app.PAUSEButton = uibutton(app.AudioMainUIFigure, 'state');
            app.PAUSEButton.ValueChangedFcn = createCallbackFcn(app, @PAUSEButtonValueChanged, true);
            app.PAUSEButton.Enable = 'off';
            app.PAUSEButton.Text = 'PAUSE';
            app.PLAYButton = uibutton(app.AudioMainUIFigure, 'push');
            app.PLAYButton.ButtonPushedFcn = createCallbackFcn(app, @PLAYButtonPushed2, true);
            app.PLAYButton.Enable = 'off';
            app.PLAYButton.Text = 'PLAY';
            app.AmplitudeALabel = uilabel(app.AudioMainUIFigure);
            app.AmplitudeALabel.Text = 'Amplitude, A';
            app.AmplitudeAEditField = uieditfield(app.AudioMainUIFigure, 'numeric');
            app.FrequencyAEditFieldLabel = uilabel(app.AudioMainUIFigure);
            app.FrequencyAEditFieldLabel.Text = 'Frequency, A';
            app.FrequencyAEditField = uieditfield(app.AudioMainUIFigure, 'numeric');
            app.VolumeControlButtonGroup = uibuttongroup(app.AudioMainUIFigure);
            app.VolumeControlButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @VolumeControlButtonGroupSelectionChanged, true);
            app.VolumeControlButtonGroup.Title = 'Volume Control';
            app.neg50 = uitogglebutton(app.VolumeControlButtonGroup);
            app.neg50.Enable = 'off';
            app.neg50.Text = '-50%';
            app.Normal = uitogglebutton(app.VolumeControlButtonGroup);
            app.Normal.Enable = 'off';
            app.Normal.Text = 'Normal';
            app.Normal.Value = true;
            app.plus50 = uitogglebutton(app.VolumeControlButtonGroup);
            app.plus50.Enable = 'off';
            app.plus50.Text = '+50%';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Audio

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AudioMainUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AudioMainUIFigure)
        end
    end
end