classdef Image < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        LoadImageButton         matlab.ui.control.Button
        CnvrtandBrightenButton  matlab.ui.control.Button
        CropImageButton         matlab.ui.control.Button
        LogTransformButton      matlab.ui.control.Button
        GammaCorrectionButton   matlab.ui.control.Button
        ImageOperationsLabel    matlab.ui.control.Label
        DisplayRGBButton        matlab.ui.control.Button
    end


    properties (Access = public)
        Property % Description
        image_filename;
        imgpath;
        rgbImg;
        full_file;
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             [app.image_filename,app.imgpath] = uigetfile({'*.jpg';'*.TIF';'*.png';'*.jpeg'},'Select an audio file');
           
            if (app.image_filename == 0)
                
                 uialert(app.UIFigure,'No Image file loaded!','Alert!','Icon','warning');         
                 return;
            else
                app.full_file = fullfile(app.imgpath,app.image_filename);
                app.rgbImg = imread(app.full_file); 
                figure('Name','Original Image','NumberTitle','Off');
                imshow(app.rgbImg);
                app.DisplayRGBButton.Enable = 'On';
                app.CnvrtandBrightenButton.Enable = 'On';
                app.CropImageButton.Enable = 'On';
                app.LogTransformButton.Enable = 'On';
                app.GammaCorrectionButton.Enable = 'On';
            end 
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
                startupFcn(app);
        end

        % Button pushed function: CnvrtandBrightenButton
        function CnvrtandBrightenButtonPushed(app, event)
                function res = brightenImg(v , img)
                    res = img; 
                    if ((v + res) > 255)
                        res = res + 255;
                    else
                        res =res + v;
                    end
                end
            
                grayImg = rgb2gray(app.rgbImg);
                value = inputdlg('Type in a value to increase brightness of the image.');
                v = str2num(value{1});
             
                figure('Name','Converted to Grayscale and Brightened','NumberTitle','Off');
                imshow(brightenImg(v,grayImg));
                 
        end

        % Button pushed function: CropImageButton
        function CropImageButtonPushed(app, event)
                [x,y] = (size(app.rgbImg));
                xmin = (x/9+4);
                ymin = (y/9+4);
                width = (x*.8);
                height =(y*.7);
                crppdImg = imcrop(app.rgbImg,[xmin, ymin, height, width]);
          
                imwrite(crppdImg,'Cropped_image.jpg');
                figure('Name','Cropped Image','NumberTitle','Off');
                imshow(crppdImg);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            close all
            delete(app)
            Main
            
        end

        % Button pushed function: LogTransformButton
        function LogTransformButtonPushed(app, event)
            function res = log_transf(img)
               
               res = double(img); 
               max_input_pixel =  double(max(img(:)));
               c = 255/(log(1 + max_input_pixel));  % this c ensures max output is 255.
          
             
               res = c * log(res+1);
               res = uint8(res);
               
            end 
            
            figure('Name','Cropped Image','NumberTitle','Off');
            imshow(log_transf(app.rgbImg));
        end

        % Button pushed function: GammaCorrectionButton
        function GammaCorrectionButtonPushed(app, event)
            function res = gamma_crct(img)
                res = img;
                res = double(res);
                gamma = 2.2;
                c=  255 ^ (1-gamma);
                res = c * (res .^ gamma);
                res = uint8(res);
            end 
            
            figure('Name','Gamma Corrected Image','NumberTitle','Off');
            imshow(gamma_crct(app.rgbImg));
                
        end

        % Button pushed function: DisplayRGBButton
        function DisplayRGBButtonPushed(app, event)
            red_ch = app.rgbImg(:,:,1);  
            blue_ch = app.rgbImg(:,:,2);
            green_ch = app.rgbImg(:,:,3);


             figure('Name','Red Channel','NumberTitle','Off');
             imshow((red_ch));
             figure('Name','Blue Channel','NumberTitle','Off');
             imshow(blue_ch);
             figure('Name','Green Channel','NumberTitle','Off');
             imshow(green_ch);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image ';
            app.CnvrtandBrightenButton = uibutton(app.UIFigure, 'push');
            app.CnvrtandBrightenButton.ButtonPushedFcn = createCallbackFcn(app, @CnvrtandBrightenButtonPushed, true);
            app.CnvrtandBrightenButton.Enable = 'off';
            app.CnvrtandBrightenButton.Text = 'Cnvrt and Brighten';
            app.CropImageButton = uibutton(app.UIFigure, 'push');
            app.CropImageButton.ButtonPushedFcn = createCallbackFcn(app, @CropImageButtonPushed, true);
            app.CropImageButton.Enable = 'off';
            app.CropImageButton.Text = 'Crop Image';
            app.LogTransformButton = uibutton(app.UIFigure, 'push');
            app.LogTransformButton.ButtonPushedFcn = createCallbackFcn(app, @LogTransformButtonPushed, true);
            app.LogTransformButton.Enable = 'off';
            app.LogTransformButton.Text = 'Log Transform';
            app.GammaCorrectionButton = uibutton(app.UIFigure, 'push');
            app.GammaCorrectionButton.ButtonPushedFcn = createCallbackFcn(app, @GammaCorrectionButtonPushed, true);
            app.GammaCorrectionButton.Enable = 'off';
            app.GammaCorrectionButton.Text = 'Gamma Correction ';
            app.ImageOperationsLabel = uilabel(app.UIFigure);
            app.ImageOperationsLabel.Text = 'Image Operations';
            app.DisplayRGBButton = uibutton(app.UIFigure, 'push');
            app.DisplayRGBButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayRGBButtonPushed, true);
            app.DisplayRGBButton.Enable = 'off';
            app.DisplayRGBButton.Text = 'Display RGB';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Image

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end