classdef Video < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        LoadButton                matlab.ui.control.Button
        VideoInformationLabel     matlab.ui.control.Label
        VideoInformationTextArea  matlab.ui.control.TextArea
        PlayButton                matlab.ui.control.Button
        PlayFromButton            matlab.ui.control.Button
    end


    properties (Access = public)
        Property % Description
        vid_filename;
        vidpath;
        vid;
        full_file;
        vid_frames; 
        vidinfo;
        vid0bj ;
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            [app.vid_filename,app.vidpath] = uigetfile({'*.mp4'},'Select an audio file');
           
            if (app.vid_filename == 0)
                
                 uialert(app.UIFigure,'No video file loaded!','Alert!','Icon','warning');         
                 return;
            else 
                app.VideoInformationTextArea.Value = "";             
                app.full_file = fullfile(app.vid,app.vid_filename);
                app.vid = VideoReader(app.full_file); 
                app.vidinfo = get(app.vid);
               
                app.VideoInformationTextArea.Enable = "On";
                app.PlayButton.Enable = "On";               
                app.PlayFromButton.Enable = "On";
                
                
                app.VideoInformationTextArea.HorizontalAlignment = 'left';
                
%                Text Area information. 
                infoArray{1} = sprintf('\n\n%s%s\n\n','Name: ',app.vid_filename);
                infoArray{2} = sprintf('%s%.2f\n\n','Duration: ', app.vidinfo.Duration); 
                infoArray{3} = sprintf('%s%i%s%i%s\n\n','Pixel Dimensions: ',app.vidinfo.Width,' x ',app.vidinfo.Height,' pixels');
                infoArray{4} = sprintf('%s%s\n\n','Dimensionality: ', app.vidinfo.VideoFormat);
                infoArray{5} = sprintf('%s%i\n\n','Bits per pixel: ',app.vidinfo.BitsPerPixel);
                infoArray{6} = sprintf('%s%i\n\n','Frames per second: ',app.vidinfo.FrameRate);
                infoArray{7} = sprintf('%s%.0f\n\n','Frames within video clip: ',app.vidinfo.FrameRate*app.vidinfo.Duration);
         
                app.VideoInformationTextArea.Value = infoArray;
                
                
            end 
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            startupFcn(app);
                        
        end

        % Value changed function: VideoInformationTextArea
        function VideoInformationTextAreaValueChanged(app, event)
            value = app.VideoInformationTextArea.Value;
            
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
    
          figure(1)
          for i=1:app.vid.NumberOfFrames
              app.vid_frames=read(app.vid,i);
              imshow(app.vid_frames,[]);
          end
% end
%         while hasFrame(app.vid)
%             app.vid_frames = read(app.vid);
%             figure(1),imshow(app.vid_frames,[]);
%         end
        end

        % Button pushed function: PlayFromButton
        function PlayFromButtonPushed(app, event)
            val = inputdlg('Please input time to start video from. e.g 4.2');
            start = str2double(val{1}) * app.vid.FrameRate;
            if (start > app.vid.NumberOfFrames)
                 uialert(app.UIFigure,'Timestamp longer than duration! Lower your value','Alert!','Icon','warning');    
                 PlayFromButtonPushed(app, event);
            end
            figure(1);
            for i=start:app.vid.NumberOfFrames
              app.vid_frames=read(app.vid,i);
              imshow(app.vid_frames,[]);
            end
        end

        % Callback function
        function PauseButtonPushed(app, event)
           
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            close all
            delete(app)
            Main
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.VideoInformationLabel = uilabel(app.UIFigure);
            app.VideoInformationLabel.Text = 'Video Information:  ';
            app.VideoInformationTextArea = uitextarea(app.UIFigure);
            app.VideoInformationTextArea.ValueChangedFcn = createCallbackFcn(app, @VideoInformationTextAreaValueChanged, true);
            app.VideoInformationTextArea.Editable = 'off';
            app.VideoInformationTextArea.Enable = 'off';
            app.VideoInformationTextArea.Value = {''; ''; ''; ''; ''; ''; ''; ''; 'No Video Loaded'};
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Enable = 'off';
            app.PlayButton.Text = 'Play';
            app.PlayFromButton = uibutton(app.UIFigure, 'push');
            app.PlayFromButton.ButtonPushedFcn = createCallbackFcn(app, @PlayFromButtonPushed, true);
            app.PlayFromButton.Enable = 'off';
            app.PlayFromButton.Text = 'Play From...';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Video

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end